classdef InstrumentCreationUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InstrumentCreationTab       matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        CreateInstrumentButton      matlab.ui.control.Button
        CreateInstrumentTestButton  matlab.ui.control.Button
        TestInstrumentButton        matlab.ui.control.Button
        MoBSTerInstrumentCreationUILabel  matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        Label_3                     matlab.ui.control.Label
        findusefulLabel             matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        thisastheywishLabel         matlab.ui.control.Label
        Label3                      matlab.ui.control.Label
        aswellasotherusefulinformationLabel  matlab.ui.control.Label
        TrajectoriesPlotTab         matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        trajectoryaxis              matlab.ui.control.UIAxes
        OtherInformationTab         matlab.ui.container.Tab
        ParticleExitPositionsPanel  matlab.ui.container.Panel
        exitpositionsAxis           matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        ParticlesenteredLabel       matlab.ui.control.Label
        ParticlesexitedLabel        matlab.ui.control.Label
        RuntimesLabel               matlab.ui.control.Label
        PenteredL                   matlab.ui.control.Label
        PexitedL                    matlab.ui.control.Label
        RuntimeL                    matlab.ui.control.Label
        RuntimejustshowstimetakentopassthroughLabel  matlab.ui.control.Label
        yourinstrumentLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateInstrumentButton
        function CreateInstrumentButtonPushed(app, event)
            open newinstrument.m
        end

        % Button pushed function: CreateInstrumentTestButton
        function CreateInstrumentTestButtonPushed(app, event)
            open instrumenttest.m
        end

        % Button pushed function: TestInstrumentButton
        function TestInstrumentButtonPushed(app, event)
            [particles,trajectories,runtime,Nin,Nout] = instrumenttest();
            %% plotter
            %This section of code is identical to that in the
            %PlotTrajectories.m function - it creates visualisations of up
            %to 1000 trajectories on the trajectory axis/
            cla(app.trajectoryaxis)
            x = zeros(1,trajectories(1).Numberofentries);
            y = zeros(1,trajectories(1).Numberofentries);
            z = zeros(1,trajectories(1).Numberofentries);
            hold(app.trajectoryaxis)
            grid(app.trajectoryaxis)
            if numel(trajectories)<1000
                num = numel(trajectories);
            else
                num = 1000;
            end
            for i = 1:num
                for p = 1:trajectories(i).Numberofentries
                    x(p) = trajectories(i).position(p,1);
                    y(p) = -trajectories(i).position(p,2);
                    z(p) = trajectories(i).position(p,3);
                end
                plot3(app.trajectoryaxis,z,y,x)
            end
            xlabel(app.trajectoryaxis,['z'])
            ylabel(app.trajectoryaxis,['-y'])
            zlabel(app.trajectoryaxis,['x'])
            hold(app.trajectoryaxis)
            
            %this section of code updates the labels such that they show
            %the instrument runtime, number of particles in and out.
            app.PenteredL.Text = int2str(Nin);
            app.PexitedL.Text = int2str(Nout);
            app.RuntimeL.Text = num2str(runtime);
            
            %This section of code generates a scatter plot of the exit x
            %and y positions of the particles. 
            cla(app.exitpositionsAxis)
            hold(app.exitpositionsAxis)
            for i = 1:num
                scatter(app.exitpositionsAxis,-1*particles(i).position(2),particles(i).position(1))
            end
            xlabel(app.exitpositionsAxis,['-y'])
            ylabel(app.exitpositionsAxis,['x'])
            hold(app.exitpositionsAxis)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstrumentCreationTab = uitab(app.TabGroup);
            app.InstrumentCreationTab.Title = 'Instrument Creation';
            app.GridLayout = uigridlayout(app.InstrumentCreationTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CreateInstrumentButton = uibutton(app.GridLayout2, 'push');
            app.CreateInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @CreateInstrumentButtonPushed, true);
            app.CreateInstrumentButton.Text = 'Create Instrument';
            app.CreateInstrumentTestButton = uibutton(app.GridLayout2, 'push');
            app.CreateInstrumentTestButton.ButtonPushedFcn = createCallbackFcn(app, @CreateInstrumentTestButtonPushed, true);
            app.CreateInstrumentTestButton.Text = 'Create Instrument Test';
            app.TestInstrumentButton = uibutton(app.GridLayout2, 'push');
            app.TestInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @TestInstrumentButtonPushed, true);
            app.TestInstrumentButton.Text = 'Test Instrument';
            app.MoBSTerInstrumentCreationUILabel = uilabel(app.GridLayout);
            app.MoBSTerInstrumentCreationUILabel.Text = 'MoBSTer Instrument Creation UI';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'For help on instrument creation please refer to the relevant section in the manual.';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '- The ''Create Instrument'' button opens a matlab file containing an instrument template, which includes sections of code that the user may';
            app.findusefulLabel = uilabel(app.GridLayout3);
            app.findusefulLabel.Text = 'find useful. ''Create Instrument Test'' opens another matlab file which allows users to create a simple test of their instrument.';
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = '- By default, the instrument test is the users instrument placed in front of a source-hexapole-dipole set-up, of course the user is free to change';
            app.thisastheywishLabel = uilabel(app.GridLayout3);
            app.thisastheywishLabel.Text = 'this as they wish.';
            app.Label3 = uilabel(app.GridLayout3);
            app.Label3.Text = '- Once the instrument test has been created, the user can press ''Test Instrument''. The next tabs will display a 3D plot of up to 500 ';
            app.aswellasotherusefulinformationLabel = uilabel(app.GridLayout3);
            app.aswellasotherusefulinformationLabel.Text = ' trajectories as well as other information about the created instrument that the user can use to see if it''s running as expected.';
            app.TrajectoriesPlotTab = uitab(app.TabGroup);
            app.TrajectoriesPlotTab.Title = 'Trajectories Plot';
            app.Panel = uipanel(app.TrajectoriesPlotTab);
            app.trajectoryaxis = uiaxes(app.Panel);
            title(app.trajectoryaxis, '')
            xlabel(app.trajectoryaxis, 'X')
            ylabel(app.trajectoryaxis, 'Y')
            app.OtherInformationTab = uitab(app.TabGroup);
            app.OtherInformationTab.Title = 'Other Information';
            app.ParticleExitPositionsPanel = uipanel(app.OtherInformationTab);
            app.ParticleExitPositionsPanel.Title = 'Particle Exit Positions';
            app.exitpositionsAxis = uiaxes(app.ParticleExitPositionsPanel);
            title(app.exitpositionsAxis, '')
            xlabel(app.exitpositionsAxis, 'X')
            ylabel(app.exitpositionsAxis, 'Y')
            app.Panel_2 = uipanel(app.OtherInformationTab);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.ParticlesenteredLabel = uilabel(app.GridLayout4);
            app.ParticlesenteredLabel.Text = 'Particles entered:';
            app.ParticlesexitedLabel = uilabel(app.GridLayout4);
            app.ParticlesexitedLabel.Text = 'Particles exited:';
            app.RuntimesLabel = uilabel(app.GridLayout4);
            app.RuntimesLabel.Text = 'Runtime (s):';
            app.PenteredL = uilabel(app.GridLayout4);
            app.PenteredL.Text = '';
            app.PexitedL = uilabel(app.GridLayout4);
            app.PexitedL.Text = '';
            app.RuntimeL = uilabel(app.GridLayout4);
            app.RuntimeL.Text = '';
            app.RuntimejustshowstimetakentopassthroughLabel = uilabel(app.GridLayout4);
            app.RuntimejustshowstimetakentopassthroughLabel.Text = '(Runtime just shows time taken to pass through ';
            app.yourinstrumentLabel = uilabel(app.GridLayout4);
            app.yourinstrumentLabel.Text = 'your instrument!)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InstrumentCreationUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end