classdef mobsterUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComponentParametersTab          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EditParametersButton            matlab.ui.control.Button
        InitialiseButton                matlab.ui.control.Button
        EquipmentVisualisationTab       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        InvestigationTab                matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        TemplateforSpinEchoInvestigationButton  matlab.ui.control.Button
        TemplateforISFgenerationButton  matlab.ui.control.Button
        BlankTemplateButton             matlab.ui.control.Button
        PlotTrajectoriesButton          matlab.ui.control.Button
        TrajectoriesTab                 matlab.ui.container.Tab
        trajectoryaxis                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EditParametersButton
        function EditParametersButtonPushed(app, event)
            open initialise.m;
        end

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            %This section loads data into the table and plots the machine
            %layout
            cla(app.UIAxes) %clear the axes
            initialise(); %initalise the function
            t = readtable('InstrumentParameters.xlsx','Sheet',1);
            app.UITable.Data = t; %read the data stored to the table
            app.UITable.ColumnName = t.Properties.VariableNames;
            ylabel(app.UIAxes,['-y']);
            xlabel(app.UIAxes,['z']);
            zlabel(app.UIAxes,['x']); %Set the axes labels
            hold(app.UIAxes);
            %plot each instrument, starting at the origin with the
            %correct length vectors
            for i = 1:numel(t(:,'name'))
                z = [table2array(t(i,"origin_3")), table2array(t(i,"origin_3"))+table2array(t(i,"length"))*cosd(table2array(t(i,"angles_3")))*cosd(table2array(t(i,"angles_2")))];
                y = -[table2array(t(i,"origin_2")), table2array(t(i,"origin_2"))+table2array(t(i,"length"))*sind(table2array(t(i,"angles_3")))*cosd(table2array(t(i,"angles_2")))];
                x = [table2array(t(i,"origin_1")), table2array(t(i,"origin_1"))+table2array(t(i,"length"))*sind(table2array(t(i,"angles_2")))];
                plot3(app.UIAxes,z,y,x,'-x');
               
            end
            %Create the lengend.
            legend(app.UIAxes,table2array(t(:,"name")),'Location','northeast')
            hold(app.UIAxes)
        end

        % Button pushed function: BlankTemplateButton
        function BlankTemplateButtonPushed(app, event)
            open blanktemplate.m
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: 
        % TemplateforSpinEchoInvestigationButton
        function TemplateforSpinEchoInvestigationButtonPushed(app, event)
            open spinechotemplate.m
        end

        % Button pushed function: TemplateforISFgenerationButton
        function TemplateforISFgenerationButtonPushed(app, event)
            open ISFtemplate.m
        end

        % Button pushed function: PlotTrajectoriesButton
        function PlotTrajectoriesButtonPushed(app, event)
            %% plotter
            %This is a section identical to the PlotTrajectories.m
            %function- but just with the target axis being the
            %trajectories.m axis in the UI.
            cla(app.trajectoryaxis)
            trajectories = evalin('base','trajectories');
            x = zeros(1,trajectories(1).Numberofentries);
            y = zeros(1,trajectories(1).Numberofentries);
            z = zeros(1,trajectories(1).Numberofentries);
            hold(app.trajectoryaxis)
            grid(app.trajectoryaxis)
            if numel(trajectories)<1000
                num = numel(trajectories);
            else
                num = 1000;
            end
            for i = 1:num
                for p = 1:trajectories(i).Numberofentries
                    x(p) = trajectories(i).position(p,1);
                    y(p) = -trajectories(i).position(p,2);
                    z(p) = trajectories(i).position(p,3);
                end
                plot3(app.trajectoryaxis,z,y,x)
            end
            xlabel(app.trajectoryaxis,['z'])
            ylabel(app.trajectoryaxis,['-y'])
            zlabel(app.trajectoryaxis,['x'])
            hold(app.trajectoryaxis)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComponentParametersTab = uitab(app.TabGroup);
            app.ComponentParametersTab.Title = 'ComponentParameters';
            app.GridLayout2 = uigridlayout(app.ComponentParametersTab);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout = uigridlayout(app.Panel);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.EditParametersButton = uibutton(app.GridLayout3, 'push');
            app.EditParametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditParametersButtonPushed, true);
            app.EditParametersButton.Text = 'Edit Parameters';
            app.InitialiseButton = uibutton(app.GridLayout3, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.EquipmentVisualisationTab = uitab(app.TabGroup);
            app.EquipmentVisualisationTab.Title = 'EquipmentVisualisation';
            app.GridLayout5 = uigridlayout(app.EquipmentVisualisationTab);
            app.Panel_3 = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel_3);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InvestigationTab = uitab(app.TabGroup);
            app.InvestigationTab.Title = 'Investigation';
            app.GridLayout7 = uigridlayout(app.InvestigationTab);
            app.TemplateforSpinEchoInvestigationButton = uibutton(app.GridLayout7, 'push');
            app.TemplateforSpinEchoInvestigationButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateforSpinEchoInvestigationButtonPushed, true);
            app.TemplateforSpinEchoInvestigationButton.Text = 'Template for Spin Echo Investigation';
            app.TemplateforISFgenerationButton = uibutton(app.GridLayout7, 'push');
            app.TemplateforISFgenerationButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateforISFgenerationButtonPushed, true);
            app.TemplateforISFgenerationButton.Text = 'Template for ISF generation';
            app.BlankTemplateButton = uibutton(app.GridLayout7, 'push');
            app.BlankTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @BlankTemplateButtonPushed, true);
            app.BlankTemplateButton.Text = 'Blank Template';
            app.PlotTrajectoriesButton = uibutton(app.GridLayout7, 'push');
            app.PlotTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoriesButtonPushed, true);
            app.PlotTrajectoriesButton.Text = 'Plot Trajectories';
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.trajectoryaxis = uiaxes(app.TrajectoriesTab);
            title(app.trajectoryaxis, '')
            xlabel(app.trajectoryaxis, 'X')
            ylabel(app.trajectoryaxis, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobsterUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end